classdef applicacao < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ControledeProcessosRemotoscomAtrasodePropagaoLabel  matlab.ui.control.Label
        IdentificaodeProcessoseSintoniadeControladoresPIDLabel  matlab.ui.control.Label
        ProjetoPrticodeSistemasEmbarcadosLabel  matlab.ui.control.Label
        Image                 matlab.ui.control.Image
        CalcularerrosButton   matlab.ui.control.Button
        Panel                 matlab.ui.container.Panel
        UIAxes_2              matlab.ui.control.UIAxes
        ErroTextArea          matlab.ui.control.TextArea
        GrficosDropDown       matlab.ui.control.DropDown
        GrficosDropDownLabel  matlab.ui.control.Label
        AplicarButton         matlab.ui.control.Button
        TiEditField           matlab.ui.control.NumericEditField
        TiEditFieldLabel      matlab.ui.control.Label
        TdEditField           matlab.ui.control.NumericEditField
        TdEditFieldLabel      matlab.ui.control.Label
        KpEditField           matlab.ui.control.NumericEditField
        KpEditFieldLabel      matlab.ui.control.Label
    end

    
    properties (Access = public)
        Step, Time, Output, Method, tempo, degrau, potenciaMotor
        Tempo, Amplitude
    end

    
    methods (Access = public)
        
        function [k, tau, theta] = identificacaoSistemas(app, Step, Time, Output, Method)
 
          arguments
              app
              Step (1,1) double {mustBeFinite, mustBeNonzero, mustBeReal, mustBeNumeric}
              Time (1,:) {mustBeNonempty}
              Output (1,:) {mustBeNonempty}
              Method (1,:) char {mustBeNonzeroLengthText, mustBeText, mustBeMember(Method, {'Smith' 'Sundaresan'})}
          end
        
          initialOutput = Output(1);
          Output = Output - initialOutput;
          finalOutput = Output(end);
          k = finalOutput/Step;
        
          if(strcmp(Method, 'Smith'))
              t1 = find(Output >= finalOutput * (28.3/100));
              t1 = Time(t1(1));
              t2 = find(Output >= finalOutput * (63.2/100));
              t2 = Time(t2(1));
              tau = 1.5 *(t2-t1);
              theta = t2 - tau;
        
          else
              t1 = find(Output >= finalOutput * (35.3/100));
              t1 = Time(t1(1));
              t2 = find(Output >= finalOutput * (85.3/100));
              t2 = Time(t2(1));
              tau = 2/3*(t2-t1);
              theta = 1.3*t1 - 0.29*t2;
          end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.GrficosDropDown.Items = {'(Selecione)', 'Dataset', 'Comparação Original/Estimada' , 'Malha Aberta/Fechada' , 'Ziegler-Nichols', 'Cohen-Coon'};
           app.UIFigure.SizeChangedFcn = [];

           load("C:\Users\tcole\OneDrive\Documentos\C213-Leonardo Teodoro 5\C213-Leonardo Teodoro\Exemplos de Dataset\Datasets\Dataset_Grupo2.mat")
           [linha, coluna] = size(TARGET_DATA____ProjetoC213_Degrau);
           if linha < coluna
               app.tempo = TARGET_DATA____ProjetoC213_Degrau(1,:);
               app.degrau = TARGET_DATA____ProjetoC213_Degrau(2,:);
               app.potenciaMotor = TARGET_DATA____ProjetoC213_PotenciaMotor(2,:);
           else
               app.tempo = TARGET_DATA____ProjetoC213_Degrau(:,1);
               app.degrau = TARGET_DATA____ProjetoC213_Degrau(:,2);
               app.potenciaMotor = TARGET_DATA____ProjetoC213_PotenciaMotor(:,2);
           end
        end

        % Button pushed function: AplicarButton
        function AplicarButtonPushed(app, event)
           % Ler os valores dos campos de edição
            Kp = app.KpEditField.Value;
            Ti = app.TiEditField.Value;
            Td = app.TdEditField.Value;
        
            % Implementação do sistema com os parâmetros atuais
            mediaDegrau = mean(app.degrau);
            valorInicial = app.potenciaMotor(1);
            app.potenciaMotor = app.potenciaMotor - valorInicial;
        
            % Recalculando sys a partir dos dados atuais
            [k, tau, theta] = identificacaoSistemas(app, mediaDegrau, app.tempo, app.potenciaMotor, 'Smith');
            sys = tf(k, [tau 1], 'InputDelay', theta);
        
            % Calculando a função de transferência do controlador PID
            % Usando os parâmetros fornecidos pelo usuário
            Ki = Kp/Ti;
            Kd = Kp*Td;

            PID = tf([Kd Kp Ki], [1 0]);
            sistemaMalhaFechada = feedback(series(PID, sys), 1);
            [app.Amplitude, app.Tempo] = step(sistemaMalhaFechada);

            % Plotando a resposta do sistema em malha fechada
            cla(app.UIAxes_2);
            plot(app.UIAxes_2, app.Tempo, app.Amplitude);
            title(app.UIAxes_2, 'Resposta');
            xlabel(app.UIAxes_2, 'Tempo');
            ylabel(app.UIAxes_2, 'Amplitude');
        end

        % Value changed function: GrficosDropDown
        function GrficosDropDownValueChanged(app, event)
            % Captura a seleção do DropDown
            value = app.GrficosDropDown.Value;
            title(app.UIAxes_2, value);

            mediaDegrau = mean(app.degrau);
            valorInicial = app.potenciaMotor(1);
            app.potenciaMotor = app.potenciaMotor - valorInicial;

            [k, tau, theta] = identificacaoSistemas(app, mediaDegrau, app.tempo, app.potenciaMotor, 'Smith');
            sys = tf(k, [tau 1], 'InputDelay', theta);

            if strcmp(value, 'Dataset')
                % Caso 'Dataset': plotando os dados
                cla(app.UIAxes_2);
                xlabel(app.UIAxes_2, 'Tempo');
                ylabel(app.UIAxes_2, 'Potência/Degrau');
                plot(app.UIAxes_2, app.tempo, app.degrau, 'b--', 'linewidth', 1.25);
                hold(app.UIAxes_2, 'on');
                plot(app.UIAxes_2, app.tempo, app.potenciaMotor, 'g-', 'linewidth', 1.25);
                legend(app.UIAxes_2, 'Degrau', 'Potência motor', 'Location', 'southeast');

            elseif(strcmp(value, 'Malha Aberta/Fechada'))
                 % Malha Aberta
                 [AmplitudesAberta, ~] = step(sys*mediaDegrau, app.tempo);
                 
                 % Malha Fechada
                 sys_fechada =  feedback(sys, 1);
                 [AmplitudesFechada, ~] = step(sys_fechada*mediaDegrau, app.tempo);
                 
                 cla(app.UIAxes_2)
                 plot(app.UIAxes_2, app.tempo, AmplitudesAberta, 'm--', 'linewidth', 2.25)
                 hold(app.UIAxes_2,'on')
                 xlabel(app.UIAxes_2, 'Tempo')
                 ylabel(app.UIAxes_2, 'Amplitude')
                 plot(app.UIAxes_2, app.tempo, AmplitudesFechada, 'b-', 'linewidth', 2.25)

            elseif(strcmp(value, 'Comparação Original/Estimada'))

                [k1, tau1, theta1] = identificacaoSistemas(app, mediaDegrau, app.tempo, app.potenciaMotor, 'Smith');
                sys1 = tf(k1, [tau1 1], 'InputDelay', theta1);
                [Amplitudes1, ~] = step(sys1*mediaDegrau, app.tempo);
                if size(Amplitudes1) ~= size(app.potenciaMotor)
                    Amplitudes1 = Amplitudes1';
                end
                erro1 = sqrt(mean((Amplitudes1 - app.potenciaMotor).^2));
                
                cla(app.UIAxes_2)
                plot(app.UIAxes_2, app.tempo, app.potenciaMotor,'k')
                hold(app.UIAxes_2,'on')
                xlabel(app.UIAxes_2, 'Tempo')
                ylabel(app.UIAxes_2, 'Amplitude')
                plot(app.UIAxes_2, app.tempo, Amplitudes1, 'm--', 'linewidth', 2.25)
                
            else
                if strcmp(value, 'Ziegler-Nichols')
                    % Parâmetros PID usando Ziegler-Nichols
                    kp = 1.2 * tau / (k * theta);
                    Ti = 2 * theta; ki = kp / Ti;
                    Td = theta / 2; kd = kp * Td;
                    legenda = 'Ziegler-Nichols';
                
                elseif strcmp(value, 'Cohen-Coon')
                    % Parâmetros PID usando Cohen-Coon
                    kp = (tau/(k*theta))*((16*tau + 3*theta)/(12*tau));
                    Ti = theta * ((32 + (6 * theta / tau)) / (13 + (8 * theta / tau)));
                    ki = kp / Ti;
                    Td = (4 * theta) / (11 + (2 * theta / tau));
                    kd = kp * Td;
                    legenda = 'Cohen-Coon';
                end
    
                % Função de transferência do controlador PID
                PID = tf([kd kp ki], [1 0]);
                sistemaMalhaFechada = feedback(series(PID, sys), 1);
                [app.Amplitude, app.Tempo] = step(sistemaMalhaFechada);

                % Plotando a resposta do sistema em malha fechada
                cla(app.UIAxes_2);
                plot(app.UIAxes_2, app.Tempo, app.Amplitude);
                title(app.UIAxes_2, legenda);
                xlabel(app.UIAxes_2, 'Tempo');
                ylabel(app.UIAxes_2, 'Amplitude');
            end
        end

        % Button pushed function: CalcularerrosButton
        function CalcularerrosButtonPushed(app, event)
           mediaDegrau = mean(app.degrau);
           valorInicial = app.potenciaMotor(1);
           app.potenciaMotor = app.potenciaMotor - valorInicial;
           
           [k1, tau1, theta1] = identificacaoSistemas(app, mediaDegrau, app.tempo, app.potenciaMotor, 'Smith');
           sys1 = tf(k1, [tau1 1], 'InputDelay', theta1);
           [Amplitudes1, ~] = step(sys1 * mediaDegrau, app.tempo);
           if size(Amplitudes1) ~= size(app.potenciaMotor)
               Amplitudes1 = Amplitudes1';
           end
           erro1 = sqrt(mean((Amplitudes1 - app.potenciaMotor).^2));
           
           [k2, tau2, theta2] = identificacaoSistemas(app, mediaDegrau, app.tempo, app.potenciaMotor, 'Sundaresan');
           sys2 = tf(k2, [tau2 1], 'InputDelay', theta2);
           [Amplitudes2, ~] = step(sys2 * mediaDegrau, app.tempo);
           if size(Amplitudes2) ~= size(app.potenciaMotor)
               Amplitudes2 = Amplitudes2';
           end
           erro2 = sqrt(mean((Amplitudes2 - app.potenciaMotor).^2));

           app.ErroTextArea.Value = {['Erro Smith: ', num2str(erro1)]; ...
                                ['Erro Sundaresan: ', num2str(erro2)]};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.KpEditFieldLabel = uilabel(app.UIFigure);
            app.KpEditFieldLabel.Text = 'Kp';
            app.KpEditField = uieditfield(app.UIFigure, 'numeric');
            app.TdEditFieldLabel = uilabel(app.UIFigure);
            app.TdEditFieldLabel.Text = 'Td';
            app.TdEditField = uieditfield(app.UIFigure, 'numeric');
            app.TiEditFieldLabel = uilabel(app.UIFigure);
            app.TiEditFieldLabel.Text = 'Ti';
            app.TiEditField = uieditfield(app.UIFigure, 'numeric');
            app.AplicarButton = uibutton(app.UIFigure, 'push');
            app.AplicarButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarButtonPushed, true);
            app.AplicarButton.Text = 'Aplicar';
            app.GrficosDropDownLabel = uilabel(app.UIFigure);
            app.GrficosDropDownLabel.Text = 'Gráficos';
            app.GrficosDropDown = uidropdown(app.UIFigure);
            app.GrficosDropDown.Items = {'(Selecione)', 'Dataset', 'Malha aberta/fechada'};
            app.GrficosDropDown.ValueChangedFcn = createCallbackFcn(app, @GrficosDropDownValueChanged, true);
            app.GrficosDropDown.Value = '(Selecione)';
            app.ErroTextArea = uitextarea(app.UIFigure);
            app.ErroTextArea.Editable = 'off';
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes_2 = uiaxes(app.Panel);
            title(app.UIAxes_2, 'Selecione uma opção')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.CalcularerrosButton = uibutton(app.UIFigure, 'push');
            app.CalcularerrosButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularerrosButtonPushed, true);
            app.CalcularerrosButton.Text = 'Calcular erros';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Logo-Inatel.png');
            app.ProjetoPrticodeSistemasEmbarcadosLabel = uilabel(app.UIFigure);
            app.ProjetoPrticodeSistemasEmbarcadosLabel.Text = 'Projeto Prático de Sistemas Embarcados';
            app.IdentificaodeProcessoseSintoniadeControladoresPIDLabel = uilabel(app.UIFigure);
            app.IdentificaodeProcessoseSintoniadeControladoresPIDLabel.Text = 'Identificação de Processos e Sintonia de Controladores PID';
            app.ControledeProcessosRemotoscomAtrasodePropagaoLabel = uilabel(app.UIFigure);
            app.ControledeProcessosRemotoscomAtrasodePropagaoLabel.Text = 'Controle de Processos Remotos com Atraso de Propagação';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = applicacao

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end